classdef Cal_node_detect_App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        AstroMicroNetLabel          matlab.ui.control.Label
        text6                       matlab.ui.control.Label
        dis_there_slider            matlab.ui.control.Slider
        sturturetable               matlab.ui.control.Table
        uibuttongroup1              matlab.ui.container.ButtonGroup
        export_node                 matlab.ui.control.Button
        export_signal               matlab.ui.control.Button
        plot_image                  matlab.ui.control.Button
        contrast_value              matlab.ui.control.EditField
        text3                       matlab.ui.control.Label
        data_adress_text            matlab.ui.control.Label
        load_data                   matlab.ui.control.Button
        uipanel1                    matlab.ui.container.Panel
        Step5Label                  matlab.ui.control.Label
        Step4Label                  matlab.ui.control.Label
        signal_detect               matlab.ui.control.Button
        export_stats                matlab.ui.control.Button
        StructureSegmentationPanel  matlab.ui.container.Panel
        MicrodomainButton           matlab.ui.control.StateButton
        Step3Label                  matlab.ui.control.Label
        Step2Label                  matlab.ui.control.Label
        Step1Label_3                matlab.ui.control.Label
        reset_sturct                matlab.ui.control.Button
        main_sturrture              matlab.ui.control.Button
        cellbutton                  matlab.ui.control.Button
        enlarge                     matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ParameterAdjustmentPanel    matlab.ui.container.Panel
        core_precent_slider         matlab.ui.control.Slider
        text5                       matlab.ui.control.Label
        core_precent_edit           matlab.ui.control.EditField
        dis_there_edit              matlab.ui.control.EditField
        axes1                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        DrawingLoopActive logical = false;
    end
    methods (Access = private)
        function StructShow(app, hObject, eventdata, handles, boundary, StructName)
            if ~isempty(StructName)
                show_StructName = StructName(cell2mat(StructName(:,2))==1,:);
            else
                show_StructName = [];
            end
            set(handles.sturturetable, 'data', show_StructName)
            axes(handles.axes1)
            imshow(handles.contrastData), hold on
            
            boundaryAndTextOnImage(boundary, 0,0, StructName), hold off
            handles.structName = StructName;
            guidata(hObject, handles);
        end
        
        function StructName = fsyncSaveMicrodomainPara(app, B)
            % 保存微域的参数
            if ~isempty(B)
                for i = 1 : length(B)
                    if i == 1
            
                        StructName = {'microdomain'}; % 保存名称
                    else
                        StructName = [StructName; {'microdomain'}]; % 保存名称
                    end
                end
            else
                StructName = [];
                fprintf("未检测到microdomain\n");
            end
        end
        function [finalBoundary, mask] = drawMicrodomainLoop(app, handles)
            % Manual microdomain drawing:
            % returns a set of boundaries and corresponding 2D masks (cell arrays)

            finalBoundary = cell(0,1);   % each cell: one microdomain boundary (same style as autoDetectMicrodomain)
            mask          = cell(0,1);    % each cell: one 2D mask for that microdomain
            usedMask      = false(size(handles.contrastData,1), size(handles.contrastData,2));
            while handles.isDrawing == 1
                drawnow;  % allow the button to be clicked again to stop

                % draw one ROI (double-click to finish)
                roi = drawfreehand(handles.axes1);
                if isempty(roi) || ~isvalid(roi)
                    continue;
                end

                handmask = createMask(roi);

                % restrict ROI region if needed
                
                if isfield(handles,'mask') && isfield(handles.mask,'cellDelMS')
                    curMask = handmask .* handles.mask.cellDelMS;
                else
                    curMask = handmask;
                end
                curMask = curMask & ~usedMask;
                % if nothing left after removing overlap, skip
                if ~any(curMask(:))
                    % optional: uialert/app message here
                    continue;
                end

                % update usedMask now (so next ROI cannot overlap)
                usedMask = usedMask | curMask;
                % boundary of this ROI (pick the largest connected component)
                [B, ~] = bwboundaries(curMask, 8);
                if isempty(B)
                    continue;
                end

                if numel(B) > 1
                    k = 1;
                    for i = 2:numel(B)
                        if numel(B{k}) < numel(B{i})
                            k = i;
                        end
                    end
                    curBoundary = B(k);
                else
                    curBoundary = B;
                end

                % save ONE microdomain result (append to the set)
                finalBoundary{end+1,1} = curBoundary;
                mask{end+1,1}          = curMask;
            end
            % 保存（如果你还想沿用原函数）
            StructName = fsyncSaveMicrodomainPara(app, curBoundary);

            if ~isfield(handles,'microdomain')
                handles.microdomain.microdomainBoundary = curBoundary;
                handles.microdomain.StructName = StructName;
            else
                handles.microdomain.microdomainBoundary = [handles.microdomain.microdomainBoundary; curBoundary];
                handles.microdomain.StructName = [handles.microdomain.StructName; StructName];
            end
            % 允许 UI 响应“第二次点击结束”
            drawnow;
        end


        function [boundary, StructName] = getAllBoudary(app, handles)
            
            if isfield(handles, 'boundary')
                handles = rmfield(handles, 'boundary');
            end
            if isfield(handles, 'StructName')
                handles = rmfield(handles, 'StructName');
            end
            
            boundary = cell(0);  % 边界
            StructName = cell(0);    % 名称 + 是否显示
            
            if isfield(handles, 'Cell')
            
                boundary = [boundary; handles.Cell.CellBoundary];
                for i = 1 : length(handles.Cell.StructName)
                    StructName = [StructName; {handles.Cell.StructName{i}, true}];
                end
            end
            
            if isfield(handles, 'mainsturcture')
            
                boundary = [boundary; handles.mainsturcture.Boundary];
                for i = 1 : length(handles.mainsturcture.StructName)
                    StructName = [StructName; {handles.mainsturcture.StructName{i}, true}];
                end
            end
            
            if isfield(handles, 'microdomain')
            
                boundary = [boundary; handles.microdomain.microdomainBoundary];
                for i = 1 : length(handles.microdomain.StructName)
                    StructName = [StructName; {handles.microdomain.StructName{i},true}];
                end
            end
        end
        
        function sturturetable_CellEditCallback(app, hObject, eventdata, handles)
            
            StructName = get(handles.sturturetable, 'data');
            StructShow(app, hObject, eventdata, handles,  handles.microdomain.microdomainBoundary, StructName)
            
            guidata(hObject, handles);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.sturturetable.BackgroundColor = [1 1 1];
            app.sturturetable.ColumnFormat = {[] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Cal_node_detect_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Cal_node_detect (see VARARGIN)
            
            % Choose default command line output for Cal_node_detect
            handles.output = hObject;
            set(handles.axes1, 'XTick', [], 'YTick', [],'XColor', 'none', 'YColor', 'none');
            handles.dis_therehold = 0;
            handles.core_precent = 0.2;
            handles.isDrawing = false;
            set(handles.dis_there_slider,'Value',0);
            set(handles.core_precent_slider,'Value',0.2);
            set(handles.dis_there_edit,'String',0);
            set(handles.core_precent_edit,'String',0.2);
            app.MicrodomainButton.BackgroundColor = [0.94 0.94 0.94]; 
            %定义统计全局变量
            handles.core_para = table( [], [], [] ,  'VariableNames', {'CoreCount', 'NonCoreCount', 'CoreRatio'}  );
            handles.core_contact = table([], [], [],'VariableNames', {'Core-Core', 'Core-NonCore', 'NonCore-NonCore'});
            handles.average_cor = table([], [], [],'VariableNames', {'Core-Core', 'Core-NonCore', 'NonCore-NonCore'});
            handles.average_dis = table([], [], [],'VariableNames', {'Core-Core', 'Core-NonCore', 'NonCore-NonCore'});
            handles.coredepart_threshold_all = [];
            handles.cor_dis_record = {{};{}};
            guidata(hObject, handles);
        end

        % Button pushed function: cellbutton
        function cellbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to cellbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.cellbutton, 'backgroundcolor', [1.00, 0.00, 0.00])%设位置按键颜色
            axes(handles.axes1)
            cla
            imshow(handles.contrastData)
            roi = images.roi.Freehand;
            roi = drawfreehand;
            mask = createMask(roi);
            [B,~,~,~] = bwboundaries(mask,8);
            
            % 单细胞，如果连通域过多，取最大的
            
            if length(B) > 1
                k = 1;
                for i = 1 : length(B)
                    if length(B{k}) < length(B{i})
                        k = i;
                    end
                end
                handles.curBoundary = B(k);
            else
                handles.curBoundary = B;    % 保存当前的连通域
            end
            
            % handles.boundary = handles.curBoundary; % 所有连通域
            
            handles.Cell.CellMask = mask;  % 单细胞掩膜
            
            handles.Cell.CellBoundary = handles.curBoundary;   % 单细胞连通域
            handles.Cell.StructName = {'Cell'}; % 保存名称
            [boundary, StructName] = getAllBoudary(app, handles);
            handles.boundary = boundary;
            handles.StructName = StructName;
            StructShow(app, hObject, eventdata, handles, boundary, StructName)
            set(handles.cellbutton, 'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'off')
            set(handles.main_sturrture, 'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'on') % 按键可用性设置
            
            guidata(hObject, handles);
        end

        % Value changed function: core_precent_edit
        function core_precent_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to core_precent_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of core_precent_edit as text
            %        str2double(get(hObject,'String')) returns contents of core_precent_edit as a double
            core_precent_Str = get(handles.core_precent_edit, 'string');
            core_precent = str2double(core_precent_Str);
            set(handles.core_precent_slider, 'Value', core_precent,'min', 0, 'max', 1);
            handles.core_precent = core_precent;
            guidata(hObject, handles);
        end

        % Value changed function: core_precent_slider
        function core_precent_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to core_precent_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            core_precent_Str = get(handles.core_precent_slider, 'Value');
            core_precent = core_precent_Str;
            set(handles.core_precent_edit, 'string', core_precent,'min', 0, 'max', 1);
            handles.core_precent = core_precent;
            guidata(hObject, handles);
        end

        % Callback function
        function core_sort_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            assert(isfield(handles,'astromicronetResult'),'Run node detection first.');
            astromicronet_gui_show(handles,'network');
            guidata(hObject,handles);
        end

        % Callback function
        function core_stats_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles = astromicronet_gui_statistics(handles);
            astromicronet_gui_show(handles,'statistics');
            guidata(hObject,handles);
        end

        % Value changed function: dis_there_edit
        function dis_there_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dis_there_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of dis_there_edit as text
            %        str2double(get(hObject,'String')) returns contents of dis_there_edit as a double
            dis_therehold_Str = get(handles.dis_there_edit, 'string');
            dis_therehold = str2double(dis_therehold_Str);
            set(handles.dis_there_slider, 'Value', dis_therehold,'min', 0, 'max', 1);
            handles.dis_therehold = dis_therehold;
            guidata(hObject, handles);
        end

        % Value changed function: dis_there_slider
        function dis_there_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dis_there_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            dis_therehold_Str = get(handles.dis_there_slider, 'Value');
            dis_therehold = dis_therehold_Str;
            set(handles.dis_there_edit, 'string', dis_therehold,'min', 0, 'max', 1);
            handles.dis_therehold = dis_therehold;
            guidata(hObject, handles);
        end

        % Button pushed function: enlarge
        function enlarge_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to enlarge (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %删除之前细胞参数
            app.MicrodomainButton.BackgroundColor = [0.94 0.94 0.94]; 
            handles.contrastData = handles.contrastData_original;
            handles.rawData = handles.rawData_original;
            handles.video = handles.video_original;
            set(handles.enlarge, 'backgroundcolor', [1.00, 0.00, 0.00])%设位置按键颜色
            set([handles.main_sturrture,handles.main_sturrture,handles.MicrodomainButton...
                ,handles.signal_detect],...
                'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'off') % 按键可用性设置
            
            if isfield(handles, 'Cell')%清除该图片中上一个细胞数据
                handles = rmfield(handles, 'Cell');
            end
            if isfield(handles, 'mainsturcture')
                handles = rmfield(handles, 'mainsturcture');
            end
            if isfield(handles, 'microdomain')
                handles = rmfield(handles, 'microdomain');
            end
            if isfield(handles, 'mask')
                handles = rmfield(handles, 'mask');
            end
            if isfield(handles, 'boundary')
                handles = rmfield(handles, 'boundary');
                handles = rmfield(handles, 'StructName');
            end
            if isfield(handles, 'rawSignal')
                handles = rmfield(handles, 'rawSignal');
                handles = rmfield(handles, 'brounary_micro');
                handles = rmfield(handles, 'rawSignal_micro');
                handles = rmfield(handles, 'dfSignal_micro');
                handles = rmfield(handles, 'calcium_info');
            end
            
            
            axes(handles.axes1)
            cla
            imshow(handles.contrastData)
            axis tight;
            
            axis image;
            set(handles.axes1, 'XLimMode', 'auto', 'YLimMode', 'auto');
            contrastData = handles.contrastData;
            rawData = handles.rawData;
            video = handles.video;
            roi = images.roi.Freehand;
            roi = drawfreehand;
            mask = createMask(roi);
            [B,~,~,~] = bwboundaries(mask,8);
            coordinates = B(1,1);
            coordinates = cell2mat(coordinates);


            x_1 = max(coordinates(:,1));
            x_2 = min(coordinates(:,1));
            y_1 = max(coordinates(:,2));
            y_2 = min(coordinates(:,2));
            
            % 保存截取图像位置，即xy初始截取长度
            handles.cutlength.x = x_2-1;
            handles.cutlength.y = y_2-1;
            handles.cutlength.x_2 = x_2;
            handles.cutlength.y_2 = y_2;
            handles.cutlength.x_1 = x_1;
            handles.cutlength.y_1 = y_1;
            
            contrastData = contrastData(x_2:x_1,:);
            contrastData = contrastData(:,y_2:y_1);
            rawData = rawData(x_2:x_1,:);
            rawData = rawData(:,y_2:y_1);
            video.videoData = handles.video.videoData(x_2:x_1,y_2:y_1,:);
            
            
            axes(handles.axes1)
            clc
            imshow(contrastData)
            handles.contrastData = contrastData;
            handles.rawData = rawData;
            handles.video = video;
            [boundary, StructName] = getAllBoudary(app, handles);
            handles.boundary = boundary;
            handles.StructName = StructName;
            StructShow(app, hObject, eventdata, handles, boundary, StructName)
            set(handles.enlarge, 'backgroundcolor', [0.94, 0.94, 0.94])
            set([handles.cellbutton,handles.reset_sturct], 'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'on') % 按键可用性设置
            guidata(hObject, handles);
        end

        % Button pushed function: export_node
        function export_node_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            astromicronet_gui_export(handles,'nodes');
        end

        % Button pushed function: export_signal
        function export_signal_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            astromicronet_gui_export(handles,'signals');
        end

        % Button pushed function: export_stats
        function export_stats_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            astromicronet_gui_export(handles,'statistics');
        end

        % Button pushed function: load_data
        function load_data_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to load_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                fprintf("正在读取...\n");
                [file, path] = uigetfile('*.*');
                set(handles.data_adress_text, 'String', strcat(path,'\',file));
                if sum(file ~= 0) ~= 0 || sum(path ~= 0) ~= 0
                    data_path = [path, file];
                    [~,~,ext] = fileparts(data_path);
            
            
                    if strcmp(ext, '.avi')
                        [data, dataInfo] = readAviSeq(data_path);
                         contrast_value = get(handles.contrast_value, 'string');
                         contrast_value = str2double(contrast_value);
            
                        if contrast_value > 0
                          contrastRatio = contrast_value;
                        else
                            contrastRatio = 0.15;%默认对比度0.15
                            set(handles.contrast_value, 'String', 0.15);
                        end
                        handles.video.videoData = data;
                        handles.video.videoInfo = dataInfo;
                        handles.data_path = data_path;
                        [path_dir, ~, ~] = fileparts(fileparts(data_path));
                        handles.path_dir = path_dir;
                        dataDouble = double(handles.video.videoData);
                        meanData = uint8(mean(dataDouble, 3));
                        handles.meanData = meanData;
                        handles.rawData = handles.meanData;
                        handles.contrastRatio = contrastRatio;
            
                        handles.rawData_original = handles.rawData;%返回切割切割前细胞所用
            
            
                        handles.contrastData = adapthisteq(handles.rawData, 'ClipLimit', contrastRatio);
                        handles.contrastData_original = handles.contrastData;
                        handles.video_original = handles.video;


                        axes(handles.axes1)
                        imshow(handles.contrastData)
                         % 显示路径
                        set(handles.data_adress_text, 'String', data_path);
                        fprintf("读取完成\n")
                    else
                        fprintf("不支持的文件类型\n")
                    end
                end
            catch me
                fprintf("读取失败\n")
                rethrow(me)
            end
            guidata(hObject, handles);
        end

        % Button pushed function: main_sturrture
        function main_sturrture_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to main_sturrture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.main_sturrture, 'backgroundcolor', [1.00, 0.00, 0.00])%设位置按键颜色
            
            roi = images.roi.Freehand;
            roi = drawfreehand;
            handmask = createMask(roi);
            if ~isfield(handles, 'mask')
                mask = handmask.*handles.Cell.CellMask;
            else
                mask = handmask.*handles.mask.cellDelMS;
            end
            [B,~,~,~] = bwboundaries(mask,8);
            
            if length(B) > 1
                k = 1;
                for i = 1 : length(B)
                    if length(B{k}) < length(B{i})
                        k = i;
                    end
                end
                handles.curBoundary = B(k);
            else
                handles.curBoundary = B;    % 保存当前的连通域
            end
            
            if ~isfield(handles, 'mainsturcture')
                handles.mainsturcture.Mask = mask;  % 单细胞掩膜
                handles.mainsturcture.Boundary = handles.curBoundary;   % 单细胞连通域
                handles.mainsturcture.StructName = {'Mainsturcture'};
            else
                handles.mainsturcture.Mask = cat(3, handles.mainsturcture.Mask, mask);
                handles.mainsturcture.Boundary = [handles.mainsturcture.Boundary; handles.curBoundary];
                handles.mainsturcture.StructName = [handles.mainsturcture.StructName; {'Mainsturcture'}]; % 保存名称
            end
            
            if ~isfield(handles, 'mask')
                handles.mask.cellDelMS = handles.Cell.CellMask -mask;
            else
                handles.mask.cellDelMS = handles.mask.cellDelMS - mask;
            end
            
            [boundary, StructName] = getAllBoudary(app, handles);
            handles.boundary = boundary;
            handles.StructName = StructName;
            
            axes(handles.axes1)
            imshow(handles.contrastData), hold on
            
            boundaryAndTextOnImage(boundary, 0,0, []), hold off
            handles.structName = StructName;
            guidata(hObject, handles);
            
            set(handles.MicrodomainButton, 'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'on') % 按键可用性设置
            
            
            guidata(hObject, handles);
        end

        % Callback function
        function mdbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to mdbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.MicrodomainButton, 'backgroundcolor', [1.00, 0.00, 0.00])%设位置按键颜色
            handles.mainsturtboundary = handles.boundary;
            handles.isDrawing = ~handles.isDrawing;
            zoom_vaule = 4;
            zoom_mutiple = zoom_vaule/2;
            zoom_mutiple =  zoom_mutiple*zoom_mutiple;
                      

            if handles.isDrawing             
                drawMicrodomainLoop(app,handles);   % 开始循环（一直画多个）
            else

            end


             %handles.mask.otherMask = handles.mask.cellDelSomaAndMainBranch;
            %  if  strcmp(handles.StructName{end, 1} ,'End Feet')
            %      handles.mask.otherMask = handles.mask.cellDelSoma;
            %      for i = 1 : length(handles.mainBranch.mainBranchMask(1, 1, :))
            %          handles.mask.otherMask = handles.mask.otherMask - handles.mainBranch.mainBranchMask(:, :, i);
            %      end
            %      for i = 1 : length(handles.endFeet.endFeetMask(1, 1, :))
            %          handles.mask.otherMask = handles.mask.otherMask - handles.endFeet.endFeetMask(:, :, i);
            %      end
            %
            %  else
            %      handles.mask.otherMask = handles.mask.cellDelSoma;
            %      for i = 1 : length(handles.mainBranch.mainBranchMask(1, 1, :))
            %          handles.mask.otherMask = handles.mask.otherMask - handles.mainBranch.mainBranchMask(:, :, i);
            %      end
            %  end
            %         case 1  % 自动检测
            if isfield(handles, 'microdomain')
                handles = rmfield(handles, 'microdomain');  % 清除
            end
            handles.otherData = double(handles.contrastData).*  handles.mask.cellDelMS;
            tmp_max = double(max(handles.otherData, [], 'all'));    % 最大像素点
            image_normalization = handles.otherData / tmp_max;   % 归一化
            handles.image_normalization = image_normalization;
            
            meanImage = mean(handles.image_normalization(handles.image_normalization ~= 0));  % 均值
            stdImage = std(handles.image_normalization(handles.image_normalization ~= 0));    % 标准差
            
            
            % 最小强度阈值
            thresholdIntensityMin = meanImage + 0.5 * stdImage;
            % 最小面积阈值
            minAreaThreshold = 10*zoom_mutiple;
            % 最大面积阈值
            maxAreaThreshold  = 200*zoom_mutiple;
            
            [B, ~] = autoDetectMicrodomain(handles.image_normalization, thresholdIntensityMin, minAreaThreshold, maxAreaThreshold);
            StructName = fsyncSaveMicrodomainPara(app,  B);
            handles.microdomain.StructName = StructName;
            handles.microdomain.microdomainBoundary = B;
            handles.microdomain.para.thresholdIntensityMin = thresholdIntensityMin;
            handles.microdomain.para.minAreaThreshold = minAreaThreshold;
            handles.microdomain.para.maxAreaThreshold = maxAreaThreshold;

            %         case 2  % 手动检测

            % 手动检测结束
            
            [boundary, StructName] = getAllBoudary(app, handles);  % 保存所有boundary
            handles.boundary = boundary;
            handles.StructName = StructName;
            axes(handles.axes1)
            imshow(handles.contrastData), hold on
            boundaryAndTextOnImage(boundary, 0,0, StructName), hold off
            
            guidata(hObject, handles);
            
            StructShow(app, hObject, eventdata, handles, boundary, StructName)
            set([handles.MicrodomainButton,handles.main_sturrture],'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'off')
            set(handles.signal_detect, 'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'on') % 按键可用性设置
        end

        % Callback function
        function node_detect_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles = astromicronet_gui_analyze(handles);
            astromicronet_gui_show(handles,'core');
            guidata(hObject,handles);
        end

        % Callback function
        function para_active_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles = astromicronet_gui_accumulate(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: plot_image
        function plot_image_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            astromicronet_gui_export_image(handles);
        end

        % Button pushed function: reset_sturct
        function reset_sturct_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            app.MicrodomainButton.BackgroundColor = [0.94 0.94 0.94]; 
            % hObject    handle to reset_sturct (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'mainsturcture')%清除已画细胞数据
                handles = rmfield(handles, 'mainsturcture');
%                 handles = rmfield(handles, 'mainsturtboundary');
            end
            if isfield(handles, 'microdomain')
                handles = rmfield(handles, 'microdomain');
            end
            if isfield(handles, 'microdomain')
                handles = rmfield(handles, 'microdomain');
            end
            if isfield(handles, 'mask')
                handles = rmfield(handles, 'mask');
            end
            if isfield(handles, 'boundary')
                handles = rmfield(handles, 'boundary');
                handles = rmfield(handles, 'StructName');
            end
            
            
            set([handles.cellbutton,handles.main_sturrture,handles.MicrodomainButton], ...
                'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'on')
            
            axes(handles.axes1)
            imshow(handles.contrastData);
%             boundaryAndTextOnImage(handles.Cell.CellBoundary, 0,0, {0,1});hold off
            set(handles.sturturetable, 'data', {})
            
            [boundary, StructName] = getAllBoudary(app, handles);
            handles.boundary = boundary;
            handles.StructName = StructName;
            StructShow(app, hObject, eventdata, handles, boundary, StructName)
            guidata(hObject, handles);
        end

        % Button pushed function: signal_detect
        function signal_detect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to signal_detect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % df/f
            [rawSignal] = rawSignalOnROI(handles.video.videoData, handles.boundary);
            % [rawSignal] = rawSignalOnROI(handles.video.videoData, edge_first_ROI);
            calcium_signal = getDF(rawSignal, 5);
            % 检测钙信号参数
            for i = 1 : length(calcium_signal(1,:))
                dfSignal = calcium_signal(:,i);
                filter_low_start = 0.05;
                filter_low_finish = 0.05;
                [start_tmp_merge, peak_tmp_merge, finish_tmp_merge] = ...
                    autoDetectCalcium(dfSignal,  filter_low_start, filter_low_finish);
            
                calcium_info{i}.signal.dfSignal = dfSignal;
                calcium_info{i}.calciumIndex.start = start_tmp_merge;
                calcium_info{i}.calciumIndex.peak = peak_tmp_merge;
                calcium_info{i}.calciumIndex.finish = finish_tmp_merge;
                %     calcium_info{i}.ROIInfo = classfied_microdomain_struct{i, 1}.ROIInfo;
                waveParams = WaveParams(dfSignal, start_tmp_merge, finish_tmp_merge, 5);
                calcium_info{i}.waveParams = waveParams;
            
            end
            
            handles.brounary_micro = handles.microdomain.microdomainBoundary;
            handles.rawSignal_micro = rawSignal(:,end-length(handles.microdomain.microdomainBoundary)+1:end);
            handles.dfSignal_micro = calcium_signal(:,end-length(handles.microdomain.microdomainBoundary)+1:end);
            
            
            handles.calcium_signal = calcium_signal;
            handles.calcium_info = calcium_info;
            handles.rawSignal = rawSignal;
            % 展示
            visibleCalcium(calcium_info);
%             visibleCalcium_eachSave(calcium_info);
            fprintf("已绘制全部钙信号\n")
            
 
            % handles.distance_soma_micro = distance_ROI(handles.brounary_soma_micro);


            handles = astromicronet_gui_analyze(handles);
            astromicronet_gui_show(handles,'core');
            astromicronet_gui_show(handles,'statistics');
            handles = astromicronet_gui_accumulate(handles);
            guidata(hObject,handles);
        end

        % Value changed function: MicrodomainButton
        function MicrodomainButtonValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            if app.MicrodomainButton.Value == 1
                % ---- Start ----
                app.MicrodomainButton.BackgroundColor = [1 0 0];
               
                % 防止重复启动 loop（非常重要）
                if app.DrawingLoopActive
                    return;
                end
                app.DrawingLoopActive = true;

                % Loop: draw multiple ROIs
                while app.MicrodomainButton.Value == 1
                    drawnow;  % 让“再次点击”能被处理

                    if app.MicrodomainButton.Value == 0
                        break;
                    end

                    roi = drawfreehand;   % 画一个 ROI（阻塞，双击结束）
                    if isempty(roi) || ~isvalid(roi)
                        continue;
                    end

                    % ====== 你原来的保存逻辑放这里 ======
                    handmask = createMask(roi);

                   
                    if isfield(handles,'mask') && isfield(handles.mask,'cellDelMS')
                        mask = handmask .* handles.mask.cellDelMS;
                    else
                        mask = handmask;
                    end

                    [B, ~] = bwboundaries(mask, 8);
                    if isempty(B), continue; end

                    % 取最大连通域
                    if numel(B) > 1
                        k = 1;
                        for i = 2:numel(B)
                            if numel(B{k}) < numel(B{i}), k = i; end
                        end
                        curBoundary = B(k);
                    else
                        curBoundary = B;
                    end

                    if ~isfield(handles,'microdomain')
                        handles.microdomain.microdomainBoundary = curBoundary;
                        handles.microdomain.StructName = {'Microdomain'};
                    else
                        handles.microdomain.microdomainBoundary = [handles.microdomain.microdomainBoundary; curBoundary];
                        handles.microdomain.StructName = [handles.microdomain.StructName; {'Microdomain'}];
                    end

                    % Loop ended
                    app.DrawingLoopActive = false;
                    [boundary, StructName] = getAllBoudary(app, handles);  % 保存所有boundary
                    handles.boundary = boundary;
                    handles.StructName = StructName;
                    axes(handles.axes1)
                    imshow(handles.contrastData), hold on
                    boundaryAndTextOnImage(boundary, 0,0, StructName), hold off

                    guidata(hObject, handles);
                    % ===================================
                end


            else
                % ---- Finish ----
                [boundary, StructName] = getAllBoudary(app, handles);
                app.MicrodomainButton.BackgroundColor = [0 0 1];  
                StructShow(app, hObject, eventdata, handles, boundary, StructName)
                set([handles.MicrodomainButton,handles.main_sturrture],'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'off')
                set(handles.signal_detect, 'backgroundcolor', [0.94, 0.94, 0.94], 'enable', 'on') % 按键可用性设置
                % 不需要在这里做更多，while 会在 drawnow 后自然退出
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Cal_node_detect';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.Tag = 'axes1';
            app.ParameterAdjustmentPanel = uipanel(app.figure1);
            app.ParameterAdjustmentPanel.Title = 'Parameter Adjustment';
            app.dis_there_edit = uieditfield(app.ParameterAdjustmentPanel, 'text');
            app.dis_there_edit.ValueChangedFcn = createCallbackFcn(app, @dis_there_edit_Callback, true);
            app.dis_there_edit.Tag = 'dis_there_edit';
            app.core_precent_edit = uieditfield(app.ParameterAdjustmentPanel, 'text');
            app.core_precent_edit.ValueChangedFcn = createCallbackFcn(app, @core_precent_edit_Callback, true);
            app.core_precent_edit.Tag = 'core_precent_edit';
            app.text5 = uilabel(app.ParameterAdjustmentPanel);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Distance'; 'Threshold'};
            app.core_precent_slider = uislider(app.ParameterAdjustmentPanel);
            app.core_precent_slider.Limits = [0 1];
            app.core_precent_slider.MajorTicks = [];
            app.core_precent_slider.ValueChangedFcn = createCallbackFcn(app, @core_precent_slider_Callback, true);
            app.core_precent_slider.MinorTicks = [];
            app.core_precent_slider.Tag = 'core_precent_slider';
            app.Panel = uipanel(app.figure1);
            app.StructureSegmentationPanel = uipanel(app.figure1);
            app.StructureSegmentationPanel.Title = 'Structure Segmentation';
            app.enlarge = uibutton(app.StructureSegmentationPanel, 'push');
            app.enlarge.ButtonPushedFcn = createCallbackFcn(app, @enlarge_Callback, true);
            app.enlarge.Tag = 'enlarge';
            app.enlarge.Text = 'Enlarge';
            app.cellbutton = uibutton(app.StructureSegmentationPanel, 'push');
            app.cellbutton.ButtonPushedFcn = createCallbackFcn(app, @cellbutton_Callback, true);
            app.cellbutton.Tag = 'cellbutton';
            app.cellbutton.Text = 'Cell';
            app.main_sturrture = uibutton(app.StructureSegmentationPanel, 'push');
            app.main_sturrture.ButtonPushedFcn = createCallbackFcn(app, @main_sturrture_Callback, true);
            app.main_sturrture.Tag = 'main_sturrture';
            app.main_sturrture.Text = 'Main Sturcture';
            app.reset_sturct = uibutton(app.StructureSegmentationPanel, 'push');
            app.reset_sturct.ButtonPushedFcn = createCallbackFcn(app, @reset_sturct_Callback, true);
            app.reset_sturct.Tag = 'reset_sturct';
            app.reset_sturct.Text = 'Redraw Structure';
            app.Step1Label_3 = uilabel(app.StructureSegmentationPanel);
            app.Step1Label_3.Text = 'Step1';
            app.Step2Label = uilabel(app.StructureSegmentationPanel);
            app.Step2Label.Text = 'Step2';
            app.Step3Label = uilabel(app.StructureSegmentationPanel);
            app.Step3Label.Text = 'Step3';
            app.MicrodomainButton = uibutton(app.StructureSegmentationPanel, 'state');
            app.MicrodomainButton.ValueChangedFcn = createCallbackFcn(app, @MicrodomainButtonValueChanged, true);
            app.MicrodomainButton.Text = 'Microdomain';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Analysis ';
            app.uipanel1.Tag = 'uipanel1';
            app.export_stats = uibutton(app.uipanel1, 'push');
            app.export_stats.ButtonPushedFcn = createCallbackFcn(app, @export_stats_Callback, true);
            app.export_stats.Tag = 'export_stats';
            app.export_stats.Text = 'Export Statistics';
            app.signal_detect = uibutton(app.uipanel1, 'push');
            app.signal_detect.ButtonPushedFcn = createCallbackFcn(app, @signal_detect_Callback, true);
            app.signal_detect.Tag = 'signal_detect';
            app.signal_detect.Text = 'Analyze Signals';
            app.Step4Label = uilabel(app.uipanel1);
            app.Step4Label.Text = 'Step4';
            app.Step5Label = uilabel(app.uipanel1);
            app.Step5Label.Text = 'Step5';
            app.load_data = uibutton(app.figure1, 'push');
            app.load_data.ButtonPushedFcn = createCallbackFcn(app, @load_data_Callback, true);
            app.load_data.Tag = 'load_data';
            app.load_data.Text = 'Load AVI File';
            app.data_adress_text = uilabel(app.figure1);
            app.data_adress_text.Tag = 'data_adress_text';
            app.data_adress_text.Text = '';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Contrast';
            app.contrast_value = uieditfield(app.figure1, 'text');
            app.contrast_value.Tag = 'contrast_value';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Export Current Result';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.plot_image = uibutton(app.uibuttongroup1, 'push');
            app.plot_image.ButtonPushedFcn = createCallbackFcn(app, @plot_image_Callback, true);
            app.plot_image.Tag = 'plot_image';
            app.plot_image.Text = 'Export Images';
            app.export_signal = uibutton(app.uibuttongroup1, 'push');
            app.export_signal.ButtonPushedFcn = createCallbackFcn(app, @export_signal_Callback, true);
            app.export_signal.Tag = 'export_signal';
            app.export_signal.Text = 'Export Signals';
            app.export_node = uibutton(app.uibuttongroup1, 'push');
            app.export_node.ButtonPushedFcn = createCallbackFcn(app, @export_node_Callback, true);
            app.export_node.Tag = 'export_node';
            app.export_node.Text = 'Export Nodes';
            app.sturturetable = uitable(app.figure1);
            app.sturturetable.ColumnName = {'Structure Name'; 'Available'};
            app.sturturetable.ColumnEditable = [true true];
            app.sturturetable.Tag = 'sturturetable';
            app.dis_there_slider = uislider(app.figure1);
            app.dis_there_slider.Limits = [0 1];
            app.dis_there_slider.MajorTicks = [];
            app.dis_there_slider.ValueChangedFcn = createCallbackFcn(app, @dis_there_slider_Callback, true);
            app.dis_there_slider.MinorTicks = [];
            app.dis_there_slider.Tag = 'dis_there_slider';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = {'Core '; 'Fraction'};
            app.AstroMicroNetLabel = uilabel(app.figure1);
            app.AstroMicroNetLabel.Text = 'AstroMicroNet';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cal_node_detect_App_v2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Cal_node_detect_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end